classdef senales < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ApplyButton              matlab.ui.control.Button
        SeleccionarFButtonGroup  matlab.ui.container.ButtonGroup
        F5Button                 matlab.ui.control.RadioButton
        F4Button                 matlab.ui.control.RadioButton
        F3Button                 matlab.ui.control.RadioButton
        F2Button                 matlab.ui.control.RadioButton
        F1Button                 matlab.ui.control.RadioButton
        UIAxes_5                 matlab.ui.control.UIAxes
        UIAxes_4                 matlab.ui.control.UIAxes
        UIAxes_3                 matlab.ui.control.UIAxes
        UIAxes_2                 matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties(Access = private)
        grafica = 1;
        Tperiodo = 0.05;
        Tiempo = 2;
        Amplitud = 2;
        FMuestreo = 30;
        Frecuencia = 15;
        Frecuencia2 = 15;
        Ttotal = 0; 
        FStem = 0:30:150;
        Senal = 0;%sin(2*pi*app.Ttotal*app.Frecuencia)+app.amplitud
        SignalStem = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StarFunFcn(app)
            app.Ttotal = 0:1/app.FMuestreo:app.Tiempo;
            app.Senal = sin(2*pi*app.Ttotal*app.Frecuencia)+app.Amplitud;
            app.SignalStem = sin(2*pi*app.FStem*app.Frecuencia2)+app.Amplitud;
        end

        % Selection changed function: SeleccionarFButtonGroup
        function SeleccionarFButtonGroupSelectionChanged(app, event)
            selectedButton = app.SeleccionarFButtonGroup.SelectedObject;
           
            switch selectedButton
            case  app.F1Button 
                app.grafica = 1;
            case  app.F2Button
                app.grafica = 2;
            case  app.F3Button
                app.grafica = 3;
            case  app.F4Button
                app.grafica = 4;
            case  app.F5Button
                app.grafica = 5;
            end 
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
              app.Ttotal = 0:1/app.FMuestreo:app.Tiempo;
            app.Senal = sin(2*pi*app.Ttotal*app.Frecuencia)+app.Amplitud;
            plot(app.UIAxes,app.Ttotal,app.Senal)
            stem(app.UIAxes,app.FStem,app.SignalStem)
        hold(app.UIAxes);       
        end

        % Button down function: UIAxes_2
        function UIAxes_2ButtonDown(app, event)
         app.Ttotal = 0:1/app.FMuestreo+30:app.Tiempo;
            app.Senal = sin(2*pi*app.Ttotal*app.Frecuencia)+app.Amplitud;
            plot(app.UIAxes_2,app.Ttotal,app.Senal)
        hold(app.UIAxes_2);           
        end

        % Button down function: UIAxes_3
        function UIAxes_3ButtonDown(app, event)
            app.Ttotal = 0:1/app.FMuestreo+60:app.Tiempo;
            app.Senal = sin(2*pi*app.Ttotal*app.Frecuencia)+app.Amplitud;
            plot(app.UIAxes_3,app.Ttotal,app.Senal)
            hold(app.UIAxes_3);           
        end

        % Button down function: UIAxes_4
        function UIAxes_4ButtonDown(app, event)
            app.Ttotal = 0:1/app.FMuestreo+90:app.Tiempo;
            app.Senal = sin(2*pi*app.Ttotal*app.Frecuencia)+app.Amplitud;
            plot(app.UIAxes_4,app.Ttotal,app.Senal)
            hold(app.UIAxes_4);       
        end

        % Button down function: UIAxes_5
        function UIAxes_5ButtonDown(app, event)
            app.Ttotal = 0:1/app.FMuestreo+120:app.Tiempo;
            app.Senal = sin(2*pi*app.Ttotal*app.Frecuencia)+app.Amplitud;
            plot(app.UIAxes_5,app.Ttotal,app.Senal)
            stem(app.UIAxes,app.FStem,app.SignalStem)
            hold(app.UIAxes_5);       
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            switch app.grafica 
            case  1
                app.Frecuencia = 2;
                app.Senal = sin(2*pi*app.Ttotal*app.Frecuencia)+app.Amplitud;
                plot(app.UIAxes,app.Ttotal,app.Senal)
                hold(app.UIAxes);   
            case  2
                app.Frecuencia = app.FMuestreo/8;
                app.Senal = sin(2*pi*app.Ttotal*app.Frecuencia)+app.Amplitud;
                plot(app.UIAxes_2,app.Ttotal,app.Senal)
                hold(app.UIAxes_2); 
            case  3
                app.Frecuencia = app.FMuestreo/4;
                app.Senal = sin(2*pi*app.Ttotal*app.Frecuencia)+app.Amplitud;
                plot(app.UIAxes_3,app.Ttotal,app.Senal)     
            case  4
                app.Frecuencia = app.FMuestreo/2;
                app.Senal = sin(2*pi*app.Ttotal*app.Frecuencia)+app.Amplitud;
                plot(app.UIAxes_4,app.Ttotal,app.Senal)
                hold(app.UIAxes_4);     
            case  5
                app.Frecuencia = app.FMuestreo+20;
                 app.Senal = sin(2*pi*app.Ttotal*app.Frecuencia)+app.Amplitud;
                plot(app.UIAxes_5,app.Ttotal,app.Senal)
                 hold(app.UIAxes_5);
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.ButtonDownFcn = createCallbackFcn(app, @UIAxes_2ButtonDown, true);
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.ButtonDownFcn = createCallbackFcn(app, @UIAxes_3ButtonDown, true);
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_4.ButtonDownFcn = createCallbackFcn(app, @UIAxes_4ButtonDown, true);
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'Title')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_5.ButtonDownFcn = createCallbackFcn(app, @UIAxes_5ButtonDown, true);
            app.SeleccionarFButtonGroup = uibuttongroup(app.UIFigure);
            app.SeleccionarFButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SeleccionarFButtonGroupSelectionChanged, true);
            app.SeleccionarFButtonGroup.Title = 'Seleccionar F';
            app.F1Button = uiradiobutton(app.SeleccionarFButtonGroup);
            app.F1Button.Text = 'F1';
            app.F1Button.Value = true;
            app.F2Button = uiradiobutton(app.SeleccionarFButtonGroup);
            app.F2Button.Text = 'F2';
            app.F3Button = uiradiobutton(app.SeleccionarFButtonGroup);
            app.F3Button.Text = 'F3';
            app.F4Button = uiradiobutton(app.SeleccionarFButtonGroup);
            app.F4Button.Text = 'F4';
            app.F5Button = uiradiobutton(app.SeleccionarFButtonGroup);
            app.F5Button.Text = 'F5';
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = senales

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @StarFunFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end